classdef neurodataanalysis1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        loadtag              matlab.ui.control.Button
        uipanel1             matlab.ui.container.Panel
        text3                matlab.ui.control.Label
        AddDetails           matlab.ui.control.Button
        ConfirmDetails       matlab.ui.control.Button
        ChoosePath           matlab.ui.control.Button
        datainformationtype  matlab.ui.control.DropDown
        datainformation      matlab.ui.control.DropDown
        text10               matlab.ui.control.Label
        text11               matlab.ui.control.Label
        DetailInfo           matlab.ui.control.Label
        DataAnalysis         matlab.ui.control.Button
        uipanel7             matlab.ui.container.Panel
        text21               matlab.ui.control.Label
        text20               matlab.ui.control.Label
        Informationlist      matlab.ui.control.DropDown
        Informationtypelist  matlab.ui.control.DropDown
        ConfirmDetails2      matlab.ui.control.Button
        AddDetails2          matlab.ui.control.Button
        text19               matlab.ui.control.Label
        ChooseFileList       matlab.ui.control.DropDown
        text18               matlab.ui.control.Label
        DetailInfoSummary    matlab.ui.control.Label
        DeleteDetails        matlab.ui.control.Button
        AutoButton           matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function neurodataanalysis_OpeningFcn(app, varargin)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to neurodataanalysis (see VARARGIN)
%             
%             % Choose default command line output for neurodataanalysis
%             app.output = hObject;
%             % Update handles structure
%             guidata(hObject, handles
%             This app could be used as a function neurodataanalysis1(path,file,Details,savepath,savefile)
%             path, file are the path and filename of the information matfile from app neurodatatag
%             savepath, savefile are the path and filename of the output information matfile from app neurodataanalysis1
%             Details is the given Tag rules matrix. Sample: 'Subject type:GroupTag:GroupTaginfomation','EVTdata:EVTdataTag:EVTdataTaginfo','LFPdata:LFPdataTag:LFPdataTaginfo'}
              global objmatrix p f savepath savefile
              p=[];
              f=[];
              savepath=[];
              savefile=[];
              if nargin>1
                  p=varargin{1}; f=varargin{2}; savepath=varargin{4}; savefile=varargin{5};
                  loadtag_Callback(app,[]);
                  datainformationtype=regexpi(varargin{3}{1},':','split');
                  app.datainformationtype.Value=datainformationtype{2};
                  datainformationtype_Callback(app,[])
                  app.datainformation.Value=datainformationtype{3};
                  AddDetails_Callback(app,[])
                  ConfirmDetailsButtonPushed(app,[]);
                  ChoosePathButtonPushed(app,[]);
                  for i=2:length(varargin{3})
                      datainformationtype=regexpi(varargin{3}{i},':','split');
                      app.ChooseFileList.Value=datainformationtype{1};
                      ChooseFileList_Callback(app,[]);
                      app.Informationtypelist.Value=datainformationtype{2};
                      InformationtypelistValueChanged(app,[]);
                      app.Informationlist.Value=datainformationtype{3};
                      AddDetails2ButtonPushed(app,[]);
                      ConfirmDetails2ButtonPushed(app,[]);
                  end
                  DataAnalysisButtonPushed(app,[]);
                  delete(app);
              end
                  
        end

        % Button pushed function: AddDetails
        function AddDetails_Callback(app, event)
            % hObject    handle to AddDetails (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            informationtype=get(app.datainformationtype,'Value');
            information=get(app.datainformation,'Value');
            set(app.DetailInfo,'Text',[informationtype,':',information]);
        end

        % Value changed function: ChooseFileList
        function ChooseFileList_Callback(app, event)
            % hObject    handle to ChooseFileList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ChooseFileList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ChooseFileList
            global objmatrixtmp Tagmatrixtmp
            filetype=app.ChooseFileList.Value;
            Filelist=[];Tagmatrixtmp=[];
            for i=1:length(objmatrixtmp)
                Filetmp=eval(['objmatrixtmp(i).',filetype]);
                Tagmatrixtmp=vertcat(Tagmatrixtmp,Filetmp');
                for j=1:length(Filetmp)
                    informationtype=[];
                    try
                      informationtype=Filetmp(j).Tagcontent('fileTag');
                      Filelist=vertcat(Filelist,informationtype);
                    end
                end       
            end
            set(app.Informationtypelist,'Items',unique(Filelist));
            InformationtypelistValueChanged(app, event);
            
        end

        % Value changed function: datainformationtype
        function datainformationtype_Callback(app, event)
            % hObject    handle to datainformationtype (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns datainformationtype contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from datainformationtype
            global objmatrix
            informationtype=get(app.datainformationtype,'Value');
            information=[];
            for i=1:length(objmatrix)
                [~,informationtmp]=objmatrix(i).Tagcontent('fileTag',informationtype);
                information=vertcat(information,informationtmp);
            end
            set(app.datainformation,'Items',unique(information));
        end

        % Button pushed function: loadtag
        function loadtag_Callback(app, event)
            % hObject    handle to loadtag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global objmatrix f p
            if isempty(f)
               [f,p]=uigetfile;
            end
            objmatrix=importdata([p,f],'objmatrix');
            informationtype=[];
            for i=1:length(objmatrix)
                informationtype=vertcat(informationtype,objmatrix(i).Tagcontent('fileTag'));
            end
            set(app.datainformationtype,'Items',unique(informationtype));
            datainformationtype_Callback(app, event);
            
        end

        % Button pushed function: ConfirmDetails
        function ConfirmDetailsButtonPushed(app, event)
            Details=app.DetailInfoSummary.Text;
            set(app.DetailInfoSummary,'Text',vertcat(Details,{['Subjecttype:',app.DetailInfo.Text]}));
        end

        % Button pushed function: ChoosePath
        function ChoosePathButtonPushed(app, event)
            global objmatrix objmatrixtmp
            Details=app.DetailInfoSummary.Text;
            index=cellfun(@(x) contains(x,'Subjecttype:'),Details,'UniformOutput',1);
            Details=Details(logical(index));
            Details=cellfun(@(x) strrep(x,'Subjecttype:',''),Details,'UniformOutput',0);
            Details=cellfun(@(x) regexpi(x,':','split'),Details,'UniformOutput',0);
            for i=1:length(objmatrix)
                for j=1:length(Details)
                    bool(i,j)=objmatrix(i).Tagchoose('fileTag',Details{j}{1},Details{j}{2});
                end
            end
            objmatrixtmp=objmatrix(logical(prod(bool,2))); 
            ChooseFileList_Callback(app,event);
        end

        % Value changed function: Informationtypelist
        function InformationtypelistValueChanged(app, event)
            global Tagmatrixtmp
            value = app.Informationtypelist.Value;
            information=[];
            for i=1:length(Tagmatrixtmp)
                [~,informationtmp]=Tagmatrixtmp(i).Tagcontent('fileTag',value);
                information=vertcat(information,informationtmp);
            end
            set(app.Informationlist,'Items',unique(information));       
        end

        % Button pushed function: AddDetails2
        function AddDetails2ButtonPushed(app, event)
            filetype=get(app.ChooseFileList,'Value');
            informationtype=get(app.Informationtypelist,'Value');
            information=get(app.Informationlist,'Value');
            set(app.DetailInfo,'Text',[filetype,':',informationtype,':',information]);
        end

        % Button pushed function: ConfirmDetails2
        function ConfirmDetails2ButtonPushed(app, event)
            Details=app.DetailInfoSummary.Text;
            set(app.DetailInfoSummary,'Text',vertcat(Details,app.DetailInfo.Text));
        end

        % Button pushed function: DeleteDetails
        function DeleteDetailsButtonPushed(app, event)
            output=listdlg('PromptString','选取一个标签名和值','SelectionMode','Single','ListString', app.DetailInfoSummary.Text);
            app.DetailInfoSummary.Text(output)=[];       
        end

        % Button pushed function: DataAnalysis
        function DataAnalysisButtonPushed(app, event)
            global objmatrixtmp savepath savefile 
            %% 选取了特定群体
            Details=app.DetailInfoSummary.Text;
            typelist=app.ChooseFileList.Items;
            for i=1:length(typelist)
                try
                   index=cellfun(@(x) contains(x,[typelist{i},':']),Details,'UniformOutput',1);
                   Detailtmp=Details(logical(index));
                   Detailtmp=cellfun(@(x) strrep(x,[typelist{i},':'],''),Detailtmp,'UniformOutput',0);
                   Detailtmp=cellfun(@(x) regexpi(x,':','split'),Detailtmp,'UniformOutput',0);
                for j=1:length(objmatrixtmp)
                    bool=[];
                    filetmp=eval(['objmatrixtmp(j).',typelist{i}]);
                    for k=1:length(Detailtmp)
                        for l=1:length(filetmp)
                        bool(l,k)=filetmp(l).Tagchoose('fileTag',Detailtmp{k}{1},Detailtmp{k}{2});
                        end
                    end
                    eval(['objmatrixtmp(j).',typelist{i},'=filetmp(logical(prod(bool,2)))']);
                end
                end
            end
            if isempty(savepath)
                uisave({'objmatrixtmp','Details'});% 根据需求更改
                neurodataanalysis2(objmatrixtmp,Details);
            else
                tmp=matfile([savepath,savefile],'Writable',true);
                tmp.objmatrixtmp=objmatrixtmp;
                tmp.Details=Details;
            end
            
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'neurodataanalysis';
            app.loadtag = uibutton(app.figure1, 'push');
            app.loadtag.ButtonPushedFcn = createCallbackFcn(app, @loadtag_Callback, true);
            app.loadtag.Text = '导入标签文件';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '面板';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = '选取要分析的被试群';
            app.AddDetails = uibutton(app.uipanel1, 'push');
            app.AddDetails.ButtonPushedFcn = createCallbackFcn(app, @AddDetails_Callback, true);
            app.AddDetails.Text = '添加选取的标签信息';
            app.ConfirmDetails = uibutton(app.uipanel1, 'push');
            app.ConfirmDetails.ButtonPushedFcn = createCallbackFcn(app, @ConfirmDetailsButtonPushed, true);
            app.ConfirmDetails.Text = '确定标签信息';
            app.ChoosePath = uibutton(app.uipanel1, 'push');
            app.ChoosePath.ButtonPushedFcn = createCallbackFcn(app, @ChoosePathButtonPushed, true);
            app.ChoosePath.Text = '确定所选择的文件';
            app.datainformationtype = uidropdown(app.uipanel1);
            app.datainformationtype.Items = {};
            app.datainformationtype.ValueChangedFcn = createCallbackFcn(app, @datainformationtype_Callback, true);
            app.datainformationtype.Value = {};
            app.datainformation = uidropdown(app.uipanel1);
            app.datainformation.Items = {};
            app.datainformation.Value = {};
            app.text10 = uilabel(app.uipanel1);
            app.text10.Text = '标签名';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Text = '标签值';
            app.DetailInfo = uilabel(app.figure1);
            app.DetailInfo.Text = '';
            app.DataAnalysis = uibutton(app.figure1, 'push');
            app.DataAnalysis.ButtonPushedFcn = createCallbackFcn(app, @DataAnalysisButtonPushed, true);
            app.DataAnalysis.Text = {'选取要分析的文件进行之后的运算'; ''};
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = '面板';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Text = '标签值';
            app.text20 = uilabel(app.uipanel7);
            app.text20.Text = '标签名';
            app.Informationlist = uidropdown(app.uipanel7);
            app.Informationlist.Items = {};
            app.Informationlist.Value = {};
            app.Informationtypelist = uidropdown(app.uipanel7);
            app.Informationtypelist.Items = {};
            app.Informationtypelist.ValueChangedFcn = createCallbackFcn(app, @InformationtypelistValueChanged, true);
            app.Informationtypelist.Value = {};
            app.ConfirmDetails2 = uibutton(app.uipanel7, 'push');
            app.ConfirmDetails2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmDetails2ButtonPushed, true);
            app.ConfirmDetails2.Text = '确定标签信息';
            app.AddDetails2 = uibutton(app.uipanel7, 'push');
            app.AddDetails2.ButtonPushedFcn = createCallbackFcn(app, @AddDetails2ButtonPushed, true);
            app.AddDetails2.Text = '添加选取的标签信息';
            app.text19 = uilabel(app.uipanel7);
            app.text19.Text = '选取要分析的文件的详细信息';
            app.ChooseFileList = uidropdown(app.uipanel7);
            app.ChooseFileList.Items = {'EVTdata', 'LFPdata', 'SPKdata', 'Videodata'};
            app.ChooseFileList.ValueChangedFcn = createCallbackFcn(app, @ChooseFileList_Callback, true);
            app.ChooseFileList.Value = 'EVTdata';
            app.text18 = uilabel(app.uipanel7);
            app.text18.Text = '文件类型';
            app.DetailInfoSummary = uilabel(app.figure1);
            app.DetailInfoSummary.Text = '';
            app.DeleteDetails = uibutton(app.figure1, 'push');
            app.DeleteDetails.ButtonPushedFcn = createCallbackFcn(app, @DeleteDetailsButtonPushed, true);
            app.DeleteDetails.Text = '删除所选标签';
            app.AutoButton = uibutton(app.figure1, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
        end
    end

    methods (Access = public)

        % Construct app
        function app = neurodataanalysis1(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)neurodataanalysis_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end