classdef neurodataanalysis2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        loadtag          matlab.ui.control.Button
        Timetype         matlab.ui.container.ButtonGroup
        timepointButton  matlab.ui.control.RadioButton
        durationButton   matlab.ui.control.RadioButton
        Label            matlab.ui.control.Label
        methodlist       matlab.ui.control.DropDown
        Timepoint        matlab.ui.container.Panel
        Label_2          matlab.ui.control.Label
        timebegin        matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        timeend          matlab.ui.control.NumericEditField
        Label_4          matlab.ui.control.Label
        eventtype        matlab.ui.control.ListBox
        Duration         matlab.ui.container.Panel
        Label_5          matlab.ui.control.Label
        eventtypebegin   matlab.ui.control.ListBox
        Label_6          matlab.ui.control.Label
        eventtypeend     matlab.ui.control.ListBox
        Label_7          matlab.ui.control.Label
        Channel          matlab.ui.control.ListBox
        Analysis         matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            global objmatrixtmp Details
            if nargin>1
                if isa(varargin{1},'NeuroData')
                    objmatrixtmp=varargin{1};
                    Details=varargin{2};
                else
                    objmatrixtmp=load(varargin{1},'objmatrixtmp');
                    Details=load(varargin{2},'Details');
                    loadtagButtonPushed(app, event);
                end
            else
                objmatrixtmp=[];Details=[];
            end                                             
        end

        % Selection changed function: Timetype
        function TimetypeSelectionChanged(app, event)
            global timetype
            selectedButton = app.Timetype.SelectedObject;
            switch selectedButton.Text
                case 'timepoint'
                    set(app.Duration,'Visible','off');
                    set(app.Timepoint,'Visible','on');
                    timetype='timepoint';
                case 'duration'
                    set(app.Timepoint,'Visible','off');
                    set(app.Duration,'Visible','on');
                    timetype='duration';
            end
        end

        % Button pushed function: loadtag
        function loadtagButtonPushed(app, event)
            global objmatrixtmp Details
            methodlistlfp={'PowerSpectralDensity','TimeVaringConnectivityAnalysis','Spectrogram',...
                'InstantaneousAmplitudeCrosscorrelations','PhaseAmplitudeCoupling'};
            methodlistspk={'FiringRate','FiringProperties','PerieventFiringHistogram'};
            methodlistmix={'PhaseLockingValue','SpikeTriggeredPotential'};
            methodlistvideo={'EventModified'};
            if isempty(objmatrixtmp)
                [f,p]=uigetfile();
                a=importdata([p,f]);
                objmatrixtmp=a.objmatrixtmp;
                Details=a.Details;
            end
            EVTtype=[];channel=[];
            for i=1:length(objmatrixtmp)
                try
                EVTtypetmp=objmatrixtmp(i).EVTdata.EVTtype;
                EVTtype=vertcat(EVTtype,EVTtypetmp);
                end           
                channeltmp=objmatrixtmp(i).Tagcontent('ChannelTag');
                channel=vertcat(channel,channeltmp);
            end
            set(app.eventtype,'Items',unique(EVTtype));
            set(app.eventtypebegin,'Items',unique(EVTtype));
            set(app.eventtypeend,'Items',unique(EVTtype));
            try
                set(app.Channel,'Items',unique(channel));
            end
            index=contains(Details,'LFPdata');
            index1=contains(Details,'SPKdata');
            index3=contains(Details,'Videodata');
            if sum(index)==0
                methodlistlfp=[];
            end
            if sum(index1)==0
                methodlistspk=[];
            end
            if sum(index)*sum(index1)==0
                methodlistmix=[];
            end  
            if sum(index3)==0
                methodlistvideo=[];
            end
            set(app.methodlist,'Items',cat(1,methodlistlfp,methodlistspk,methodlistmix,methodlistvideo));
            TimetypeSelectionChanged(app, event);
        end

        % Button pushed function: Analysis
        function AnalysisButtonPushed(app, event)
            global Details objmatrixtmp timetype
            %收集所选择的信息
            DetailsAnalysis=Details;
            switch timetype
                case 'duration'
                    timebegin=app.eventtypebegin.Value;
                    timeend=app.eventtypeend.Value;
                    DetailsAnalysis=vertcat(DetailsAnalysis,['Timestart:EVTtype:',timebegin]);
                    DetailsAnalysis=vertcat(DetailsAnalysis,['Timestop:EVTtype:',timeend]);
                    DetailsAnalysis=vertcat(DetailsAnalysis,['Timetype:timeduration']);
                case 'timepoint'
                    timebegin=app.timebegin.Value;
                    timeend=app.timeend.Value;
                    eventtype=app.eventtype.Value;
                    eventtypeall=[];
                    for i=1:length(eventtype)
                        eventtypeall=[eventtypeall,eventtype{i},','];
                    end
                    eventtypeall(end)=[];
                    DetailsAnalysis=vertcat(DetailsAnalysis,['Timestart:',num2str(timebegin)]);
                    DetailsAnalysis=vertcat(DetailsAnalysis,['Timestop:',num2str(timeend)]);
                    DetailsAnalysis=vertcat(DetailsAnalysis,['Timetype:timepoint:EVTtype:',eventtypeall]);
            end
                channel=app.Channel.Value;
                channelall=[];
                try
                for i=1:length(channel)
                  channelall=[channelall,channel{i},','];
                end          
                channelall(end)=[];
                end
                DetailsAnalysis=vertcat(DetailsAnalysis,['ChannelTag:',channelall]);
            %% 此部分可以将DetailsAnalysis变量移植出来，通过更改其中的一些参数，进行循环计算，得到多条件下的运算结果。
            methodname=app.methodlist.Value;
            eval(['methodobj=',methodname,'();']);
            methodobj=methodobj.getParams(timetype);
            multiWaitbar('Calculating',0)
            savefilepath=uigetdir('储存结果的路径');
            for i=1:length(objmatrixtmp)
               objmatrixresult=methodobj.cal(objmatrixtmp(i),DetailsAnalysis);
               [~,savevariablename]=fileparts(objmatrixtmp(i).Datapath);
               savematfile=matfile(fullfile(savefilepath,[savevariablename,'.mat']),'Writable',true);
               try
                   objmatrixresult.writeData(savematfile);
                   savematfile.DetailsAnalysis=DetailsAnalysis;
               end
               multiWaitbar('Calculating',i/length(objmatrixtmp));
            end
            multiWaitbar('CloseAll');
            %% 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.loadtag = uibutton(app.UIFigure, 'push');
            app.loadtag.ButtonPushedFcn = createCallbackFcn(app, @loadtagButtonPushed, true);
            app.loadtag.Text = '导入选择好的文件信息';
            app.Timetype = uibuttongroup(app.UIFigure);
            app.Timetype.SelectionChangedFcn = createCallbackFcn(app, @TimetypeSelectionChanged, true);
            app.Timetype.Title = '事件类型';
            app.timepointButton = uiradiobutton(app.Timetype);
            app.timepointButton.Text = 'timepoint';
            app.timepointButton.Value = true;
            app.durationButton = uiradiobutton(app.Timetype);
            app.durationButton.Text = 'duration';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '分析方法';
            app.methodlist = uidropdown(app.UIFigure);
            app.methodlist.Items = {'选项 1', '选项 2', '选项 3', '选项 4', '选项 5'};
            app.methodlist.Value = '选项 1';
            app.Timepoint = uipanel(app.UIFigure);
            app.Timepoint.Title = '时间点';
            app.Label_2 = uilabel(app.Timepoint);
            app.Label_2.Text = '开始时间';
            app.timebegin = uieditfield(app.Timepoint, 'numeric');
            app.Label_3 = uilabel(app.Timepoint);
            app.Label_3.Text = '结束时间';
            app.timeend = uieditfield(app.Timepoint, 'numeric');
            app.Label_4 = uilabel(app.Timepoint);
            app.Label_4.Text = {'事件名称'; ''};
            app.eventtype = uilistbox(app.Timepoint);
            app.eventtype.Items = {};
            app.eventtype.Multiselect = 'on';
            app.eventtype.Value = {};
            app.Duration = uipanel(app.UIFigure);
            app.Duration.Title = '时间段';
            app.Label_5 = uilabel(app.Duration);
            app.Label_5.Text = {'开始事件名称'; ''};
            app.eventtypebegin = uilistbox(app.Duration);
            app.eventtypebegin.Items = {};
            app.eventtypebegin.Value = {};
            app.Label_6 = uilabel(app.Duration);
            app.Label_6.Text = {'结束事件名称'; ''};
            app.eventtypeend = uilistbox(app.Duration);
            app.eventtypeend.Items = {};
            app.eventtypeend.Value = {};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = '文件通道信息';
            app.Channel = uilistbox(app.UIFigure);
            app.Channel.Items = {};
            app.Channel.Multiselect = 'on';
            app.Channel.Value = {};
            app.Analysis = uibutton(app.UIFigure, 'push');
            app.Analysis.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.Analysis.Text = '开始分析';
        end
    end

    methods (Access = public)

        % Construct app
        function app = neurodataanalysis2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end