classdef neurodataanalysis3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Label       matlab.ui.control.Label
        FileList    matlab.ui.control.ListBox
        LoadPath    matlab.ui.control.Button
        PlotResult  matlab.ui.control.Button
        Path        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: LoadPath
        function LoadPathPushed(app, event)
            global path
            path=uigetdir('打开结果所在的文件夹');
            FileList=dir(path);
            FileList=struct2table(FileList);
            app.FileList.Items=FileList.name(~FileList.isdir);
            app.Path.Text=path;
        end

        % Value changed function: FileList
        function FileListValueChanged(app, event)

        end

        % Button pushed function: PlotResult
        function PlotResultPushed(app, event)
           global Resultfile path
            h=msgbox('Loading Data....');
           if class(app.FileList.Value)=='cell'
               for i=1:length(app.FileList.Value)
               if ispc
                Resultfile{i}=matfile([path,'\',app.FileList.Value{i}]);
               else
                 Resultfile{i}=matfile([path,'/',app.FileList.Value{i}]);
               end
               end
               methodname=Resultfile{1}.methodname; 
           else
               if ispc
                Resultfile{1}=matfile([path,'\',app.FileList.Value(i)]);
               else
                 Resultfile{1}=matfile([path,'/',app.FileList.Value(i)]);
               end
               methodname=Resultfile{1}.methodname; 
           end   
            obj=eval([methodname,'()']);
           
            obj.GenerateObjects(Resultfile);
            obj.Startupfcn(Resultfile);
            try
              close(h);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '结果文件名';
            app.FileList = uilistbox(app.UIFigure);
            app.FileList.Items = {};
            app.FileList.Multiselect = 'on';
            app.FileList.ValueChangedFcn = createCallbackFcn(app, @FileListValueChanged, true);
            app.FileList.Value = {};
            app.LoadPath = uibutton(app.UIFigure, 'push');
            app.LoadPath.ButtonPushedFcn = createCallbackFcn(app, @LoadPathPushed, true);
            app.LoadPath.Text = '导入结果文件路径';
            app.PlotResult = uibutton(app.UIFigure, 'push');
            app.PlotResult.ButtonPushedFcn = createCallbackFcn(app, @PlotResultPushed, true);
            app.PlotResult.Text = '打开文件作图';
            app.Path = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = neurodataanalysis3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end